classdef MiTAP_CTlung < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MiTAPUIFigure             matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        DataSelectionTab          matlab.ui.container.Tab
        Label                     matlab.ui.control.Label
        SelectedCasesLabel        matlab.ui.control.Label
        button_project_save       matlab.ui.control.Button
        label_catdir              matlab.ui.control.Label
        label_projdir             matlab.ui.control.Label
        ProjectDirectoryLabel     matlab.ui.control.Label
        ModuleOptionsPanel        matlab.ui.container.Panel
        label_username            matlab.ui.control.Label
        table_case                matlab.ui.control.Table
        button_preview            matlab.ui.control.Button
        button_clear              matlab.ui.control.Button
        button_submit             matlab.ui.control.Button
        modules                   matlab.ui.container.CheckBoxTree
        Module1Node               matlab.ui.container.TreeNode
        Module2Node               matlab.ui.container.TreeNode
        Module3Node               matlab.ui.container.TreeNode
        Module4Node               matlab.ui.container.TreeNode
        Module5Node               matlab.ui.container.TreeNode
        Module6Node               matlab.ui.container.TreeNode
        table_scans               matlab.ui.control.Table
        button_cat_save           matlab.ui.control.Button
        button_cat_new            matlab.ui.control.Button
        button_cat_select         matlab.ui.control.Button
        CatalogLabel              matlab.ui.control.Label
        ModulesLabel              matlab.ui.control.Label
        FilterPanel               matlab.ui.container.Panel
        QueryEditField            matlab.ui.control.EditField
        QueryEditFieldLabel       matlab.ui.control.Label
        FieldDropDown             matlab.ui.control.DropDown
        FieldDropDownLabel        matlab.ui.control.Label
        TagDropDown               matlab.ui.control.DropDown
        TagDropDownLabel          matlab.ui.control.Label
        ApplyButton               matlab.ui.control.Button
        button_project_new        matlab.ui.control.Button
        dropdown_project          matlab.ui.control.DropDown
        ProjectNameDropDownLabel  matlab.ui.control.Label
        UserNameLabel             matlab.ui.control.Label
        PreviewTab                matlab.ui.container.Tab
        ContrastSlider            matlab.ui.control.RangeSlider
        ContrastSliderLabel       matlab.ui.control.Label
        IndexSlider               matlab.ui.control.Slider
        IndexSliderLabel          matlab.ui.control.Label
        Slice0EditField           matlab.ui.control.NumericEditField
        Slice0EditFieldLabel      matlab.ui.control.Label
        Column0EditField          matlab.ui.control.NumericEditField
        Column0EditFieldLabel     matlab.ui.control.Label
        Row0EditField             matlab.ui.control.NumericEditField
        Row0EditFieldLabel        matlab.ui.control.Label
        ViewDropDown              matlab.ui.control.DropDown
        ViewDropDownLabel         matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        username
        project
        cat     % Table containing data catalog
        mod     % Pipeline modules and their options
        opt     % MiTAP options
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % Initialize properties
            app.cat = MiTAP_catalog;
            app.mod = getModules;
            app.opt = MiTAP_options;
            
            % Set username
            if ispc
                app.username = getenv('USERNAME');
            else
                app.username = getenv('USER');
            end
            app.label_username.Text = app.username;

            % Modules
            delete(app.modules.Children); % Clear defaults
            for i = 1:length(app.mod)
                uitreenode(app.modules,'Text',app.mod(i).title,'Tag',app.mod(i).tag);
            end
            app.modules.CheckedNodes = [app.modules.Children([app.mod.def])];

            % Projects
            app.project.folder = fullfile(userpath,'MiTAP','projects');
            app.dropdown_projectDropDownOpening(app,[]);

        end

        % Button pushed function: button_cat_select
        function button_cat_selectButtonPushed(app, event)

        end

        % Button pushed function: button_project_new
        function button_project_newButtonPushed(app, event)
            % Retrieve names of all saved projects

            % User inputs new name (must not be in use)

            % Create new project with defaults

            % Save as a project definition file

        end

        % Drop down opening function: dropdown_project
        function dropdown_projectDropDownOpening(app, event)
            fn = dir(fullfile(app.project.folder,'*.project.txt'));
            projects = cell(numel(fn),2); % { tag , name }
            for i = 1:numel(fn)
                projects{i,1} = extractBefore(fn(i).name,'.project.txt');
                str = fileread(fullfile(fn(i).folder,fn(i).name));
                tok = regexp(str,'\(Name ([^\)]*)','tokens');
                if ~isempty(tok)
                    projects{i,2} = tok{1}{1};
                end
            end
            app.dropdown_project.Items = [{'Default'};projects(:,2)];
            app.dropdown_project.ItemsData = [{''};projects(:,1)];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MiTAPUIFigure = uifigure('Visible', 'off');
            app.MiTAPUIFigure.Name = 'MiTAP';
            app.TabGroup = uitabgroup(app.MiTAPUIFigure);
            app.DataSelectionTab = uitab(app.TabGroup);
            app.DataSelectionTab.Title = 'Data Selection';
            app.UserNameLabel = uilabel(app.DataSelectionTab);
            app.UserNameLabel.Text = 'User Name:';
            app.ProjectNameDropDownLabel = uilabel(app.DataSelectionTab);
            app.ProjectNameDropDownLabel.Text = 'Project Name:';
            app.dropdown_project = uidropdown(app.DataSelectionTab);
            app.dropdown_project.Items = {};
            app.dropdown_project.DropDownOpeningFcn = createCallbackFcn(app, @dropdown_projectDropDownOpening, true);
            app.dropdown_project.Value = {};
            app.button_project_new = uibutton(app.DataSelectionTab, 'push');
            app.button_project_new.ButtonPushedFcn = createCallbackFcn(app, @button_project_newButtonPushed, true);
            app.button_project_new.Text = 'New';
            app.FilterPanel = uipanel(app.DataSelectionTab);
            app.FilterPanel.Title = 'Filter';
            app.ApplyButton = uibutton(app.FilterPanel, 'push');
            app.ApplyButton.Text = 'Apply';
            app.TagDropDownLabel = uilabel(app.FilterPanel);
            app.TagDropDownLabel.Text = 'Tag';
            app.TagDropDown = uidropdown(app.FilterPanel);
            app.FieldDropDownLabel = uilabel(app.FilterPanel);
            app.FieldDropDownLabel.Text = 'Field';
            app.FieldDropDown = uidropdown(app.FilterPanel);
            app.QueryEditFieldLabel = uilabel(app.FilterPanel);
            app.QueryEditFieldLabel.Text = 'Query';
            app.QueryEditField = uieditfield(app.FilterPanel, 'text');
            app.ModulesLabel = uilabel(app.DataSelectionTab);
            app.ModulesLabel.Text = 'Modules:';
            app.CatalogLabel = uilabel(app.DataSelectionTab);
            app.CatalogLabel.Text = 'Catalog:';
            app.button_cat_select = uibutton(app.DataSelectionTab, 'push');
            app.button_cat_select.ButtonPushedFcn = createCallbackFcn(app, @button_cat_selectButtonPushed, true);
            app.button_cat_select.Text = 'Select';
            app.button_cat_new = uibutton(app.DataSelectionTab, 'push');
            app.button_cat_new.Text = 'New';
            app.button_cat_save = uibutton(app.DataSelectionTab, 'push');
            app.button_cat_save.Text = 'Save';
            app.table_scans = uitable(app.DataSelectionTab);
            app.table_scans.ColumnName = {'Tag'; 'SeriesDescription'; 'SliceThickness'; 'SliceNumber'};
            app.table_scans.RowName = {};
            app.table_scans.ColumnEditable = [true false false false];
            app.modules = uitree(app.DataSelectionTab, 'checkbox');
            app.Module1Node = uitreenode(app.modules);
            app.Module1Node.Text = 'Module1';
            app.Module2Node = uitreenode(app.modules);
            app.Module2Node.Text = 'Module2';
            app.Module3Node = uitreenode(app.modules);
            app.Module3Node.Text = 'Module3';
            app.Module4Node = uitreenode(app.modules);
            app.Module4Node.Text = 'Module4';
            app.Module5Node = uitreenode(app.modules);
            app.Module5Node.Text = 'Module5';
            app.Module6Node = uitreenode(app.modules);
            app.Module6Node.Text = 'Module6';
            app.button_submit = uibutton(app.DataSelectionTab, 'push');
            app.button_submit.Text = 'Submit';
            app.button_clear = uibutton(app.DataSelectionTab, 'push');
            app.button_clear.Text = 'Clear Tags';
            app.button_preview = uibutton(app.DataSelectionTab, 'push');
            app.button_preview.Text = 'Preview';
            app.table_case = uitable(app.DataSelectionTab);
            app.table_case.ColumnName = {'Select'; 'UMlabel'; 'UMtimepoint'};
            app.table_case.RowName = {};
            app.table_case.ColumnEditable = true;
            app.table_case.Tag = 'table_case';
            app.label_username = uilabel(app.DataSelectionTab);
            app.label_username.Text = 'Username';
            app.ModuleOptionsPanel = uipanel(app.DataSelectionTab);
            app.ModuleOptionsPanel.Title = 'Module Options:';
            app.ProjectDirectoryLabel = uilabel(app.DataSelectionTab);
            app.ProjectDirectoryLabel.Text = 'Project Directory:';
            app.label_projdir = uilabel(app.DataSelectionTab);
            app.label_projdir.Text = '';
            app.label_catdir = uilabel(app.DataSelectionTab);
            app.label_catdir.Text = '';
            app.button_project_save = uibutton(app.DataSelectionTab, 'push');
            app.button_project_save.Text = 'Save';
            app.SelectedCasesLabel = uilabel(app.DataSelectionTab);
            app.SelectedCasesLabel.Text = 'Selected Cases:';
            app.Label = uilabel(app.DataSelectionTab);
            app.Label.Text = '0';
            app.PreviewTab = uitab(app.TabGroup);
            app.PreviewTab.Title = 'Preview';
            app.UIAxes = uiaxes(app.PreviewTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ViewDropDownLabel = uilabel(app.PreviewTab);
            app.ViewDropDownLabel.Text = 'View:';
            app.ViewDropDown = uidropdown(app.PreviewTab);
            app.ViewDropDown.Items = {'Slice', 'Row', 'Column'};
            app.ViewDropDown.Value = 'Slice';
            app.Row0EditFieldLabel = uilabel(app.PreviewTab);
            app.Row0EditFieldLabel.Text = 'Row (0)';
            app.Row0EditField = uieditfield(app.PreviewTab, 'numeric');
            app.Column0EditFieldLabel = uilabel(app.PreviewTab);
            app.Column0EditFieldLabel.Text = 'Column (0)';
            app.Column0EditField = uieditfield(app.PreviewTab, 'numeric');
            app.Slice0EditFieldLabel = uilabel(app.PreviewTab);
            app.Slice0EditFieldLabel.Text = 'Slice (0)';
            app.Slice0EditField = uieditfield(app.PreviewTab, 'numeric');
            app.IndexSliderLabel = uilabel(app.PreviewTab);
            app.IndexSliderLabel.Text = 'Index';
            app.IndexSlider = uislider(app.PreviewTab);
            app.IndexSlider.Step = 1;
            app.ContrastSliderLabel = uilabel(app.PreviewTab);
            app.ContrastSliderLabel.Text = 'Contrast';
            app.ContrastSlider = uislider(app.PreviewTab, 'range');
            % Show the figure after all components are created
            app.MiTAPUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MiTAP_CTlung

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.MiTAPUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.MiTAPUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MiTAPUIFigure)
        end
    end
end